classdef MitC_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MitC                            matlab.ui.Figure
        Label                           matlab.ui.control.Label
        MessageWindow                   matlab.ui.control.TextArea
        RunsimulationButton             matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        HoverLabel                      matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SetsimulationparametersPanel    matlab.ui.container.Panel
        NrsimulationsSpinnerLabel       matlab.ui.control.Label
        NrsimulationsSpinner            matlab.ui.control.Spinner
        TargetprojectdurationEditFieldLabel  matlab.ui.control.Label
        TargetprojectdurationEditField  matlab.ui.control.EditField
        PenaltySpinnerLabel             matlab.ui.control.Label
        PenaltySpinner                  matlab.ui.control.Spinner
        IncentiveSpinnerLabel           matlab.ui.control.Label
        IncentiveSpinner                matlab.ui.control.Spinner
        VersionSwitch                   matlab.ui.control.Switch
        OriginalprojectdurationTextAreaLabel  matlab.ui.control.Label
        OriginalprojectdurationTextArea  matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        DocumentationButton             matlab.ui.control.Button
        GivefeedbackButton              matlab.ui.control.Button
        SetupsimulationdataPanel        matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        VersionLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        version = 'Version 0.2.0';
%         path = addpath(genpath(pwd))
        Results
        Data
        Config = struct;       
        dataDouble
        dataCell    
        StartUp
    end
    
    methods (Access = private)              
                
        % Disable editable fields during simulation
        function initialize_simulation(app)                  
            disable_input(app);        
        end
        
        % Set lamp color to orange
        function set_lamp_orange(app)
            app.Lamp.Color = [1 0.5 0];       
        end
        
        % Set lamp color to green
        function set_lamp_green(app)
            app.Lamp.Color = [0 1 0];       
        end
        
        % Set lamp color to red
        function set_lamp_red(app)
            app.Lamp.Color = [1 0 0];       
        end
               
        % Diasble editable fields        
        function disable_input(app)
            app.LoadButton.Enable = false;
            app.NrsimulationsSpinner.Enable = false;
            app.TargetprojectdurationEditField.Enable = false;
            app.SaveButton.Enable = false;
            app.RunsimulationButton.Enable = false;
        end
        
        % Enable editable fields
        function enable_input(app)
            app.LoadButton.Enable = true;
            app.NrsimulationsSpinner.Enable = true;
            app.TargetprojectdurationEditField.Enable = true;
            app.SaveButton.Enable = true;
            app.RunsimulationButton.Enable = true;
        end
        
       % Set default values
        function set_default_values(app)
            app.NrsimulationsSpinner.Value = 5000;
            app.PenaltySpinner.Value = 8500;
            app.IncentiveSpinner.Value = 5000;
            app.OriginalprojectdurationTextArea.Value = num2str([]);
            app.TargetprojectdurationEditField.Value = num2str([]);
            
            % Reset version swithc
            app.VersionSwitch.Value = "Basic";
            app.PenaltySpinner.Enable = false;
            app.IncentiveSpinner.Enable = false;
            
        end
        
        % Vertification for enabling/disabling the Run button
        function check_run(app)
            if isfield(app.Config, 'savefolder') && ~isempty(app.dataCell) && ~isempty(app.dataDouble) && app.Config.statusData
                app.RunsimulationButton.Enable = true;
                set_lamp_green(app);
            elseif app.Config.statusData == 0
                app.RunsimulationButton.Enable = false;
                set_lamp_red(app);
            else
                app.RunsimulationButton.Enable = false;
                set_lamp_orange(app);
            end
        end
        
        % Create export directory
        function create_save_folder(app)
            if ~exist(app.Config.savefolder,'dir')
                 mkdir(app.Config.savefolder);
            end
        end
        
        
        % Get penalty and incentive values
        function get_parameter_mode(app)                             
        if strcmp(app.VersionSwitch.Value, 'Basic')
            app.Config.parameterMode = 'Basic';
            app.Config.penalty = 9999999;
            app.Config.incentive = 0;
        else
            app.Config.parameterMode = 'Advanced';
            app.Config.penalty = app.PenaltySpinner.Value;
            app.Config.incentive = app.IncentiveSpinner.Value;                
        end
            
        end
        
        % Steps for loading new project data
        function data_verification(app)
            % Verify raw data
            [app.Config.statusData, message] = verify_raw_data(app.dataDouble, app.dataCell);
            UpdateMessage(app, message);            
            
            if app.Config.statusData == 1
                % Parse raw data
                [app.Data, app.dataDouble, app.dataCell] = parse_data(app.dataDouble, app.dataCell);
                
                % Generate matrix with all paths
                app.Data = generate_paths(app.Data);
                
                % Select critical paths to reduce the simulation time
                app.Data = find_critical_path(app.Data);
                
                % Set T_orig field
                app.OriginalprojectdurationTextArea.Value = num2str(app.Data.T_orig);
                
                % Set T_target field to T_orig field
                app.TargetprojectdurationEditField.Value = num2str(app.Data.T_orig);
                
                % Plot network graph (no saving)
                plot_network(app.Data.linkedActivities, app.dataCell,...
                                 [], []);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VersionLabel.Text = app.version;
            welcomeMessage = {'Please follow these steps to simulate your mitigation measures:',...
                              '1) Load your project data',...
                              '2) Select an output directory',...
                              '3) Provide simulation parameters',...
                              '4) Run simulation',...
                              '',...
                              'For more instructions, please press the documentation button.'};
            app.MessageWindow.Value = welcomeMessage;        
            app.StartUp = 1;
            app.Config.statusData = NaN;
            
            % Set fields in GUI to default values
            set_default_values(app)
        end

        % Value changed function: MessageWindow
        function UpdateMessage(app, event)
                 
            % Update the message window
            if iscell(event)
                for i = 1 : length(event)       
                    if app.StartUp == 1
                        app.MessageWindow.Value{1} = event{i};  
                        app.StartUp = 0;
                        pause(0.01)    
                    else 
                        value = app.MessageWindow.Value; 
                        app.MessageWindow.Value = [value; event{i}];       
                        pause(0.01)                             
                    end
                                  
                end
            else   
                if app.StartUp == 1
                    app.MessageWindow.Value = event; 
                    app.StartUp = 0;
                else 
                    value = app.MessageWindow.Value; 
                    app.MessageWindow.Value = [value; event]; 
                end
                 
            end
            
            pause(0.01)
        end

        % Button pushed function: LoadButton
        function load_data(app, event)
%             UpdateMessage(app, 'Loading project data, please wait...');   
                
            % Check whether a file is already imported                        
            if ~isfield(app.Config,'file')
                importedFile = [];
            elseif ischar(app.Config.file)
                importedFile = app.Config.file;
            else
                importedFile = [];
            end
            
            % Ask user for input file
            [filename, pathname] = uigetfile('../data/*.xlsx', 'Select your project data');       
            file = [pathname filename];     
            
            % Verify user input
            [loadStatus, message] = verify_filepath(file);    
            UpdateMessage(app, message);                                          
                        
            % (Re)load project data
            if loadStatus && isempty(importedFile)
                % Load data if no data is present
                [app.dataDouble, app.dataCell] = import_project(file);   
                app.Config.file = file;
                UpdateMessage(app, "Loading  " + file);
                data_verification(app)
                check_run(app);                
            elseif loadStatus && ~isempty(importedFile)
                % Reload data if data is present
                [app.dataDouble, app.dataCell] = import_project(file);  
                app.Config.file = file;
                UpdateMessage(app, "Replacing project data, loading " + file + ".");
                data_verification(app)
                check_run(app);  
            elseif ~loadStatus && ~isempty(importedFile)
                % Cancel load procedure and keep current data
                UpdateMessage(app, "Keeping current project data: " + app.Config.file + ".");
                check_run(app);
            else
                % Cancel load procedure without importing data
                UpdateMessage(app, "No data project was selected.");
                set_lamp_red(app);
                app.Config.file = [];
                return
            end           
            
        end

        % Button pushed function: RunsimulationButton
        function run_simulation(app, event)
            initialize_simulation(app);            
            
            % Close all graphs
            close all
            
            % Create export directory
            create_save_folder(app);
            
            % Get user settings
            app.Config.nSimulations = app.NrsimulationsSpinner.Value;
            app.Config.T_target = str2double(app.TargetprojectdurationEditField.Value);
            
            % Determine penalty and incentive values
            get_parameter_mode(app)                                             
            
            % Run simulation
            UpdateMessage(app, "Running simulation, please wait...");
            [app.Results, CP_0, CP_opt, Corr_ii, cancelSimulation] = mitc_simulation(app.Data, app.Config);
                                              
            % Generate plots
            if cancelSimulation == 0
                UpdateMessage(app, "Generating and exporting plots, please wait...");
                plot_network(app.Data.linkedActivities, app.dataCell,...
                             app.Config.savefolder, 'fig_1');
                         
                plot_freq_mitigation(app.Results, app.Data, app.Config.nSimulations,...
                             app.Config.savefolder, 'fig_2');
                         
                plot_freq_paths(CP_0, CP_opt, app.Data.nPaths,...
                             app.Config.savefolder, 'fig_3');
                         
                plot_freq_activity(CP_0, CP_opt, app.Data.nPaths, app.Data.nodesInPath, app.Data.nActivities,...
                             app.Config.savefolder, 'fig_4');
                         
                plot_cdf(app.Results, app.Data.nMitigations, app.Data.T_orig, ...
                             app.Config.T_target, app.Config.nSimulations,...
                             app.Config.savefolder, 'fig_5');
                     
                plot_cost_distribution('cdf', app.Results, app.Data, app.Config, 'fig_6');
                plot_cost_distribution('pdf', app.Results, app.Data, app.Config, 'fig_7');

            end 
            
            % Save data and plots
            if cancelSimulation == 0
                UpdateMessage(app, 'Saving results...');
                export_results(app.Config, app.Data, app.Results);
                UpdateMessage(app, 'Finished!');  
            end
            
            % Display message if simulation was aborted
            if cancelSimulation == 1
                UpdateMessage(app, 'Simulation cancelled.');
                set_lamp_orange(app);
            end
                        
            set_lamp_green(app);
            enable_input(app);            
        end

        % Button pushed function: SaveButton
        function save_output(app, event)
            savedir = uigetdir('../results','Please select a directory');   
                
            if ~isfield(app.Config,'savefolder')
                importedSavedir = [];
            elseif ischar(app.Config.savefolder)
                importedSavedir = app.Config.savefolder;
            else
                importedSavedir = [];
            end            
            
            ID = datestr(now, 'yyyy-mm-dd__HH-MM-SS');
            
            if ischar(savedir) && isempty(importedSavedir)                
                app.Config.savefolder = strcat(savedir, '\', ID, '\');                
                UpdateMessage(app, "Selected output folder: " + savedir + ".");
                check_run(app);  
            elseif ischar(savedir) && ~isempty(importedSavedir)
                app.Config.savefolder = strcat(savedir, '\', ID, '\');
                UpdateMessage(app, "Replacing output folder: " + savedir + ".");
                check_run(app);  
            elseif ~ischar(savedir) && ~isempty(importedSavedir)                  
                 UpdateMessage(app, "Keeping current output folder: " + importedSavedir + ".");
                 check_run(app);  
            elseif ~ischar(savedir) && isempty(importedSavedir)                    
                UpdateMessage(app, "No output folder selected");
            end            
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            enable_input(app);
            
            % Reset GUI 
            app.RunsimulationButton.Enable = false;
            set_lamp_orange(app);
            app.MessageWindow.Value = {app.version};
            set_default_values(app)
            
            % Reset data and variable
            app.Data = [];
            app.Results = [];
            app.dataCell = [];
            app.dataDouble = [];
            app.Config = [];        
            startupFcn(app)
            close all
        end

        % Button pushed function: CloseButton
        function close(app, event)
            delete(app)
        end

        % Value changed function: VersionSwitch
        function VersionSwitchValueChanged(app, event)
            if strcmp(app.VersionSwitch.Value, 'Advanced')
                app.PenaltySpinner.Enable = true;
                app.IncentiveSpinner.Enable = true;                
            elseif strcmp(app.VersionSwitch.Value, 'Basic')
                app.PenaltySpinner.Enable = false;
                app.IncentiveSpinner.Enable = false;
            end            
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
            url = 'https://github.com/mitigation-controller/mitc/blob/main/doc/User_Manual.md';
            web(url, '-browser')
        end

        % Button pushed function: GivefeedbackButton
        function GivefeedbackButtonPushed(app, event)
            url = 'https://github.com/mitigation-controller/mitc/blob/main/CONTRIBUTING.md';
            web(url, '-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MitC = uifigure('Visible', 'off');
            app.MitC.Name = 'MitC';
            app.Label = uilabel(app.MitC);
            app.Label.Text = '';
            app.MessageWindow = uitextarea(app.MitC);
            app.MessageWindow.ValueChangedFcn = createCallbackFcn(app, @UpdateMessage, true);
            app.MessageWindow.Editable = 'off';
            app.MessageWindow.Tooltip = {''};
            app.RunsimulationButton = uibutton(app.MitC, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @run_simulation, true);
            app.RunsimulationButton.Enable = 'off';
            app.RunsimulationButton.Tooltip = {'Run the Monte Carlo simulation. '};
            app.RunsimulationButton.Text = 'Run simulation';
            app.Lamp = uilamp(app.MitC);
            app.Lamp.Tooltip = {'green: ready for simulation'; 'orange: incomplete parameters'; 'red: error'};
            app.HoverLabel = uilabel(app.MitC);
            app.HoverLabel.Text = 'Hover the cursor over a button for more information. ';
            app.ResetButton = uibutton(app.MitC, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Tooltip = {'Reset the simulation.'};
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.MitC, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.CloseButton.Text = 'Close';
            app.SetsimulationparametersPanel = uipanel(app.MitC);
            app.SetsimulationparametersPanel.Title = 'Set simulation parameters';
            app.NrsimulationsSpinnerLabel = uilabel(app.SetsimulationparametersPanel);
            app.NrsimulationsSpinnerLabel.Text = 'Nr. simulations';
            app.NrsimulationsSpinner = uispinner(app.SetsimulationparametersPanel);
            app.NrsimulationsSpinner.Step = 500;
            app.NrsimulationsSpinner.Limits = [1000 10000];
            app.NrsimulationsSpinner.Tooltip = {'Select the number of Monte Carlo iterations. Limited to [1000 10000]'};
            app.NrsimulationsSpinner.Value = 5000;
            app.TargetprojectdurationEditFieldLabel = uilabel(app.SetsimulationparametersPanel);
            app.TargetprojectdurationEditFieldLabel.Text = 'Target project duration';
            app.TargetprojectdurationEditField = uieditfield(app.SetsimulationparametersPanel, 'text');
            app.TargetprojectdurationEditField.Tooltip = {'Target project duration should be less than original project duration (in days).'};
            app.PenaltySpinnerLabel = uilabel(app.SetsimulationparametersPanel);
            app.PenaltySpinnerLabel.Text = 'Penalty';
            app.PenaltySpinner = uispinner(app.SetsimulationparametersPanel);
            app.PenaltySpinner.Step = 500;
            app.PenaltySpinner.Limits = [1 10000];
            app.PenaltySpinner.Enable = 'off';
            app.PenaltySpinner.Tooltip = {'Info to be added'};
            app.PenaltySpinner.Value = 8500;
            app.IncentiveSpinnerLabel = uilabel(app.SetsimulationparametersPanel);
            app.IncentiveSpinnerLabel.Text = 'Incentive';
            app.IncentiveSpinner = uispinner(app.SetsimulationparametersPanel);
            app.IncentiveSpinner.Step = 500;
            app.IncentiveSpinner.Limits = [1 10000];
            app.IncentiveSpinner.Enable = 'off';
            app.IncentiveSpinner.Tooltip = {'Info to be added'};
            app.IncentiveSpinner.Value = 5000;
            app.VersionSwitch = uiswitch(app.SetsimulationparametersPanel, 'slider');
            app.VersionSwitch.Items = {'Advanced', 'Basic'};
            app.VersionSwitch.ValueChangedFcn = createCallbackFcn(app, @VersionSwitchValueChanged, true);
            app.VersionSwitch.Tooltip = {'Switch to enable penalty and incentive parameters. '};
            app.VersionSwitch.Value = 'Basic';
            app.OriginalprojectdurationTextAreaLabel = uilabel(app.SetsimulationparametersPanel);
            app.OriginalprojectdurationTextAreaLabel.Text = 'Original project duration';
            app.OriginalprojectdurationTextArea = uitextarea(app.SetsimulationparametersPanel);
            app.OriginalprojectdurationTextArea.Editable = 'off';
            app.OriginalprojectdurationTextArea.Tooltip = {'Original project duration (in days).'};
            app.Image = uiimage(app.MitC);
            app.Image.ImageSource = 'MitC_logo.png';
            app.DocumentationButton = uibutton(app.MitC, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Tooltip = {'Link to the online user documentation on GitHub.'};
            app.DocumentationButton.Text = 'Documentation';
            app.GivefeedbackButton = uibutton(app.MitC, 'push');
            app.GivefeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @GivefeedbackButtonPushed, true);
            app.GivefeedbackButton.Tooltip = {'Link to the online user documentation on GitHub.'};
            app.GivefeedbackButton.Text = 'Give feedback';
            app.SetupsimulationdataPanel = uipanel(app.MitC);
            app.SetupsimulationdataPanel.Title = 'Set up simulation data ';
            app.LoadButton = uibutton(app.SetupsimulationdataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.LoadButton.Tooltip = {'Select a single project data file. Currently only .xlsx format is supported.'};
            app.LoadButton.Text = 'Load project data (.xlsx)';
            app.SaveButton = uibutton(app.SetupsimulationdataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save_output, true);
            app.SaveButton.Tooltip = {'Select the folder where you want to save the results of the simulation. '};
            app.SaveButton.Text = 'Select save folder';
            app.VersionLabel = uilabel(app.MitC);
            app.VersionLabel.Text = '';
            % Show the figure after all components are created
            app.MitC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MitC_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MitC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MitC)
        end
    end
end